classdef TermProject_MainFile < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        HowtouseTextArea        matlab.ui.control.TextArea
        HowtouseTextAreaLabel   matlab.ui.control.Label
        StartButton             matlab.ui.control.Button
        EPOCHTextArea           matlab.ui.control.TextArea
        EPOCHTextAreaLabel      matlab.ui.control.Label
        HEIGHTmSpinner          matlab.ui.control.Spinner
        HEIGHTmSpinnerLabel     matlab.ui.control.Label
        LONGSpinner             matlab.ui.control.Spinner
        LONGSpinnerLabel        matlab.ui.control.Label
        LATSpinner              matlab.ui.control.Spinner
        LATSpinnerLabel         matlab.ui.control.Label
        SKYPLOTPanel            matlab.ui.container.Panel
        GORUNDTRACKPanel        matlab.ui.container.Panel
        SatelliteDropDown       matlab.ui.control.DropDown
        SatelliteDropDownLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        AZ
        El
        lat
        long
        height
        MapAxes
        SliderValue
        value
        ground_lat
        ground_long
        ground_H
        a
        e
        i
        w
        M0
        toc
        RAAN
        v0


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SatelliteDropDown
        function SatelliteDropDownValueChanged(app, event)
            
             
            app.value = app.SatelliteDropDown.Value;
            load nav.mat;

            if (strcmp(app.value,'GPS') == 1)
                    
                       app.a = nav.GPS.a; %m
                       app.e = nav.GPS.e; 
                       app.i = rad2deg(nav.GPS.i); %rad
                       app.w = rad2deg(nav.GPS.omega); %rad
                       app.M0 = rad2deg(nav.GPS.M0);
                       app.toc = nav.GPS.toc ;
                       app.RAAN = rad2deg(nav.GPS.OMEGA);
                       app.v0 = trueanomaly(app.M0,app.e);
                       app.EPOCHTextArea.Value = num2str(app.toc);


            elseif (strcmp(app.value,'QZSS')==1)

            
            app.a = nav.QZSS.a; %m
                app.e = nav.QZSS.e; 
                app.i = rad2deg(nav.QZSS.i); %rad
                app.w = rad2deg(nav.QZSS.omega); %rad
                app.M0 = rad2deg(nav.QZSS.M0);
                app.toc = nav.QZSS.toc ;
                app.RAAN = rad2deg(nav.QZSS.OMEGA);
                
                app.v0 = trueanomaly(app.M0,app.e);
                
                app.EPOCHTextArea.Value = num2str(app.toc);


            elseif (strcmp(app.value, 'BDS')==1)
            
            app.a = nav.BDS.a; %m
            app.e = nav.BDS.e; 
            app.i = rad2deg(nav.BDS.i); %rad
            app.w = rad2deg(nav.BDS.omega); %rad
            app.M0 = rad2deg(nav.BDS.M0);
            app.toc = nav.BDS.toc ;
            app.RAAN = rad2deg(nav.BDS.OMEGA);

            app.v0 = trueanomaly(app.M0,app.e);

            app.EPOCHTextArea.Value = num2str(app.toc);


            end


        end

        % Value changed function: LATSpinner
        function LATSpinnerValueChanged(app, event)
            app.ground_lat = app.LATSpinner.Value;
            
        end

        % Value changed function: LONGSpinner
        function LONGSpinnerValueChanged(app, event)
            app.ground_long = app.LONGSpinner.Value;
            
        end

        % Value changed function: HEIGHTmSpinner
        function HEIGHTmSpinnerValueChanged(app, event)
            app.ground_H = app.HEIGHTmSpinner.Value;
            
        end

        % Value changed function: EPOCHTextArea
        function EPOCHTextAreaValueChanged(app, event)
                
            txt = app.EPOCHTextArea.Value;
            fprintf(txt);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.value = app.SatelliteDropDown.Value;

            pqw2ecimatrix = PQW2ECI(app.w,app.i,app.RAAN);
                        
                        
                        for t = 1 : 1440
                        
                           v = FinalTrueAnomaly(app.v0,app.a,app.e,t*60);
                           
                        
                        PQWR = solveRangeInPerifocalFrame(app.a,app.e,v);
                        PQWV = solveVelocityInPerifocalFrame(app.a,app.e,v);
                        
                        
                        ECIV = pqw2ecimatrix * PQWV;
                        
                        ECIR = pqw2ecimatrix * PQWR;
                        
                        ECEFR = ECI2ECEF_DCM(datetime(app.toc) + minutes(t))*ECIR ;
                        ECEFV = ECI2ECEF_DCM(datetime(app.toc) + minutes(t))*ECIV ;
                        
                        [app.lat(t),app.long(t),app.height(t)] = ecef2geodetic(wgs84Ellipsoid('meter'),ECEFR(1),ECEFR(2),ECEFR(3)); 
                        [E(t),N(t),U(t)] = ecef2enu(ECEFR(1),ECEFR(2),ECEFR(3),app.ground_lat,app.ground_long,app.ground_H,wgs84Ellipsoid);
                        app.AZ(t) = azimuth([E(t),N(t),U(t)]);
                        app.El(t) = elevation([E(t),N(t),U(t)],10);
                        
                        end
                        

                        gx = geoaxes(app.GORUNDTRACKPanel);
                        geoplot(gx,app.lat,app.long,'b-','LineWidth',1);
                        geolimits(gx,[-90,90],[-180,180]);
                        geobasemap(gx,'grayland');
                        title(gx,'groundtrack')
                        
                        skyplot(app.SKYPLOTPanel,app.AZ,app.El)
                         
                        app.StartButton.Enable = 'on';
                         
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SatelliteDropDownLabel = uilabel(app.UIFigure);
            app.SatelliteDropDownLabel.Text = 'Satellite';
            app.SatelliteDropDown = uidropdown(app.UIFigure);
            app.SatelliteDropDown.Items = {'Choose', 'GPS', 'QZSS', 'BDS'};
            app.SatelliteDropDown.ValueChangedFcn = createCallbackFcn(app, @SatelliteDropDownValueChanged, true);
            app.SatelliteDropDown.Value = 'Choose';
            app.GORUNDTRACKPanel = uipanel(app.UIFigure);
            app.GORUNDTRACKPanel.Title = 'GORUNDTRACK';
            app.SKYPLOTPanel = uipanel(app.UIFigure);
            app.SKYPLOTPanel.Title = 'SKYPLOT';
            app.LATSpinnerLabel = uilabel(app.UIFigure);
            app.LATSpinnerLabel.Text = 'LAT';
            app.LATSpinner = uispinner(app.UIFigure);
            app.LATSpinner.ValueChangedFcn = createCallbackFcn(app, @LATSpinnerValueChanged, true);
            app.LONGSpinnerLabel = uilabel(app.UIFigure);
            app.LONGSpinnerLabel.Text = 'LONG';
            app.LONGSpinner = uispinner(app.UIFigure);
            app.LONGSpinner.ValueChangedFcn = createCallbackFcn(app, @LONGSpinnerValueChanged, true);
            app.HEIGHTmSpinnerLabel = uilabel(app.UIFigure);
            app.HEIGHTmSpinnerLabel.Text = 'HEIGHT(m)';
            app.HEIGHTmSpinner = uispinner(app.UIFigure);
            app.HEIGHTmSpinner.ValueChangedFcn = createCallbackFcn(app, @HEIGHTmSpinnerValueChanged, true);
            app.EPOCHTextAreaLabel = uilabel(app.UIFigure);
            app.EPOCHTextAreaLabel.Text = 'EPOCH';
            app.EPOCHTextArea = uitextarea(app.UIFigure);
            app.EPOCHTextArea.ValueChangedFcn = createCallbackFcn(app, @EPOCHTextAreaValueChanged, true);
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.HowtouseTextAreaLabel = uilabel(app.UIFigure);
            app.HowtouseTextAreaLabel.Text = 'How to use?';
            app.HowtouseTextArea = uitextarea(app.UIFigure);
            app.HowtouseTextArea.Value = {'Enter and select all values and press the Start button to wait for 10 seconds.'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TermProject_MainFile

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end